classdef OxArpes_DataList < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Menu           matlab.ui.container.Menu
        Menu2          matlab.ui.container.Menu
        TabGroup       matlab.ui.container.TabGroup
        Tab            matlab.ui.container.Tab
        Tab2           matlab.ui.container.Tab
        RefreshButton  matlab.ui.control.Button
        UITable        matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        function results = getDataTable(app)
            vars = evalin('base','who');
            types = cell(size(vars));
            for i = 1:length(vars)
                var = evalin('base',vars{i});
                types{i} = class(var);
            end
            results = table(vars,types,'VariableNames',["Name","Type"]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            datalist = app.getDataTable();
            app.UITable.ColumnName = datalist.Properties.VariableNames;
            app.UITable.Data = datalist.Variables;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Type'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_DataList

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end