classdef map_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlPanelUIFigure   matlab.ui.Figure
        ZEditField             matlab.ui.control.NumericEditField
        YEditField             matlab.ui.control.NumericEditField
        XEditField             matlab.ui.control.NumericEditField
        ZSlider                matlab.ui.control.Slider
        ZSliderLabel           matlab.ui.control.Label
        YSlider                matlab.ui.control.Slider
        YSliderLabel           matlab.ui.control.Label
        XSlider                matlab.ui.control.Slider
        XSliderLabel           matlab.ui.control.Label
        ViewOptionButtonGroup  matlab.ui.container.ButtonGroup
        CrosshairCheckBox      matlab.ui.control.CheckBox
        ALLButton              matlab.ui.control.RadioButton
        ZButton                matlab.ui.control.RadioButton
        YButton                matlab.ui.control.RadioButton
        XButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        MAP; % Description
        dmax;
        dmin;

        FigX;
        FigY;
        FigZ;
        FigX_axe;
        FigY_axe;
        FigZ_axe;
        FigX_crosshair;
        FigY_crosshair;
        FigZ_crosshair;
    end
    
    methods (Access = private)
        
        function update_figures(app)
            %% plot Fig x
            [~,n] = min(abs(app.MAP.x - app.XSlider.Value));
            imagesc(app.FigX_axe,app.MAP.y,app.MAP.z,squeeze(app.MAP.value(n,:,:))');
            xlabel(app.FigX_axe,[app.MAP.y_name ' [' app.MAP.y_unit ']']);
            ylabel(app.FigX_axe,[app.MAP.z_name ' [' app.MAP.z_unit ']']);
%             title(axes,app.MAP.name,'interpreter', 'none');
            set(app.FigX_axe,'YDir','normal');
            colormap(app.FigX_axe,flipud(gray));
%             caxis(app.FigX_axe,[app.dmin,app.dmax]);


            %% plot Fig y
            width = 0.1;
            cond = abs(app.MAP.y - app.YSlider.Value) < width/2;
            imag = squeeze(mean(app.MAP.value(:,cond,:),2))';
            imagesc(app.FigY_axe,app.MAP.x,app.MAP.z,imag);

            xlabel(app.FigY_axe,[app.MAP.x_name ' [' app.MAP.x_unit ']']);
            ylabel(app.FigY_axe,[app.MAP.z_name ' [' app.MAP.z_unit ']']);
%             title(axes,app.MAP.name,'interpreter', 'none');
            set(app.FigY_axe,'YDir','normal');
            colormap(app.FigY_axe,flipud(gray));
%             caxis(app.FigY_axe,[app.dmin,app.dmax]);

            %% plot Fig z
            width = 0.05;
            cond = abs(app.MAP.z - app.ZSlider.Value) < width/2;
            imag = squeeze(mean(app.MAP.value(:,:,cond),3))';
            imagesc(app.FigZ_axe,app.MAP.x,app.MAP.y,imag);

            xlabel(app.FigZ_axe,[app.MAP.x_name ' [' app.MAP.x_unit ']']);
            ylabel(app.FigZ_axe,[app.MAP.y_name ' [' app.MAP.y_unit ']']);
            set(app.FigZ_axe,'DataAspectRatio',[1 1 1]);
%             title(axes,app.MAP.name,'interpreter', 'none');
            set(app.FigZ_axe,'YDir','normal');
            colormap(app.FigZ_axe,flipud(gray));
%             caxis(app.FigZ_axe,[app.dmin,app.dmax]);

            %% all
            if app.CrosshairCheckBox.Value == 1
                    app.FigX_crosshair = drawcrosshair('Parent',app.FigX_axe,'Position',[app.YSlider.Value app.ZSlider.Value],'LineWidth',1,'Color','b');
                    app.FigY_crosshair = drawcrosshair('Parent',app.FigY_axe,'Position',[app.XSlider.Value app.ZSlider.Value],'LineWidth',1,'Color','b');
                    app.FigZ_crosshair = drawcrosshair('Parent',app.FigZ_axe,'Position',[app.XSlider.Value app.YSlider.Value],'LineWidth',1,'Color','b');
                    addlistener(app.FigX_crosshair,'ROIMoved',@(src,data) app.update_sliders(app.XSlider.Value,data.CurrentPosition(1),data.CurrentPosition(2)));
                    addlistener(app.FigY_crosshair,'ROIMoved',@(src,data) app.update_sliders(data.CurrentPosition(1),app.YSlider.Value,data.CurrentPosition(2)));
                    addlistener(app.FigZ_crosshair,'ROIMoved',@(src,data) app.update_sliders(data.CurrentPosition(1),data.CurrentPosition(2),app.ZSlider.Value));

            end
        end

        function update_sliders(app,x,y,z)
            app.XSlider.Value = x;
            app.YSlider.Value = y;
            app.ZSlider.Value = z;
            app.XEditField.Value = x;
            app.YEditField.Value = y;
            app.ZEditField.Value = z;
            app.update_figures();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MAP_data)
            app.MAP = MAP_data;

            % compatibility
%             if isstruct(MAP_data)
%                 app.MAP.name = inputname(2);
%                 [app.MAP.x_name ' [' app.MAP.x_unit ']'] = 'X';
%                 [app.MAP.y_name ' [' app.MAP.y_unit ']'] = 'Y';
%                 [app.MAP.z_name ' [' app.MAP.z_unit ']'] = 'Z';
%             
%             end
            
            app.ControlPanelUIFigure.Name = ['Viewer - ' app.MAP.name];

            app.XSlider.Limits = [min(app.MAP.x) max(app.MAP.x)];
            app.YSlider.Limits = [min(app.MAP.y) max(app.MAP.y)];
            app.ZSlider.Limits = [min(app.MAP.z) max(app.MAP.z)];
            app.ZSlider.Value = (min(app.MAP.z)+max(app.MAP.z))/2;

            app.XEditField.Value = app.XSlider.Value;
            app.YEditField.Value = app.YSlider.Value;
            app.ZEditField.Value = app.ZSlider.Value;


            app.dmin = min(app.MAP.value,[],'all');
            app.dmax = max(app.MAP.value,[],'all');

            %% set upt the figures
            app.FigX = figure('Name',['Data plot - ' app.MAP.name ' X']);
            app.FigY = figure('Name',['Data plot - ' app.MAP.name ' Y']);
            app.FigZ = figure('Name',['Data plot - ' app.MAP.name ' Z']);
            app.FigX_axe = axes('parent',app.FigX);
            app.FigY_axe = axes('parent',app.FigY);
            app.FigZ_axe = axes('parent',app.FigZ);

            app.update_figures();

        end

        % Selection changed function: ViewOptionButtonGroup
        function ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewOptionButtonGroup.SelectedObject;
            option = selectedButton.Text;
            if strcmp(option,'X')
                app.XSlider.Enable = "on";
                app.YSlider.Enable = "off";
                app.ZSlider.Enable = "off";
                app.XEditField.Enable = "on";
                app.YEditField.Enable = "off";
                app.ZEditField.Enable = "off";
                app.CrosshairCheckBox.Value = 0;
                app.CrosshairCheckBox.Enable = 'off';

                % draw in the axes
                app.update_figures();

                app.FigX.Visible = 'on';
                app.FigY.Visible = 'off';
                app.FigZ.Visible = 'off';

            elseif strcmp(option,'Y')
                app.XSlider.Enable = "off";
                app.YSlider.Enable = "on";
                app.ZSlider.Enable = "off";
                app.XEditField.Enable = "off";
                app.YEditField.Enable = "on";
                app.ZEditField.Enable = "off";
                app.CrosshairCheckBox.Value = 0;
                app.CrosshairCheckBox.Enable = 'off';

                % draw in the axes
                app.update_figures();

                app.FigX.Visible = 'off';
                app.FigY.Visible = 'on';
                app.FigZ.Visible = 'off';

            elseif strcmp(option,'Z')
                app.XSlider.Enable = "off";
                app.YSlider.Enable = "off";
                app.ZSlider.Enable = "on";
                app.XEditField.Enable = "off";
                app.YEditField.Enable = "off";
                app.ZEditField.Enable = "on";
                app.CrosshairCheckBox.Value = 0;
                app.CrosshairCheckBox.Enable = 'off';

                % draw in the axes
                app.update_figures();

                app.FigX.Visible = 'off';
                app.FigY.Visible = 'off';
                app.FigZ.Visible = 'on';

            elseif strcmp(option,'ALL')
                app.XSlider.Enable = "on";
                app.YSlider.Enable = "on";
                app.ZSlider.Enable = "on";
                app.XEditField.Enable = "on";
                app.YEditField.Enable = "on";
                app.ZEditField.Enable = "on";
                app.CrosshairCheckBox.Value = 1;
                app.CrosshairCheckBox.Enable = 'on';

                % draw in the axes
                app.update_figures();

                app.FigX.Visible = 'on';
                app.FigY.Visible = 'on';
                app.FigZ.Visible = 'on';
            end
        end

        % Value changed function: CrosshairCheckBox
        function CrosshairCheckBoxValueChanged(app, event)
            value = app.CrosshairCheckBox.Value;
            app.update_figures();
        end

        % Close request function: ControlPanelUIFigure
        function ControlPanelUIFigureCloseRequest(app, event)
            try
                close(app.FigX);
                close(app.FigY);
                close(app.FigZ);
            catch
            end
            delete(app);
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value = app.XSlider.Value;
            app.XEditField.Value = value;
            app.update_figures();

        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value = app.YSlider.Value;
            app.YEditField.Value = value;
            app.update_figures();
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = app.ZSlider.Value;
            app.ZEditField.Value = value;
            app.update_figures();
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.XSlider.Value = value;
            app.update_figures();
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.YSlider.Value = value;
            app.update_figures();
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.ZSlider.Value = value;
            app.update_figures();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlPanelUIFigure = uifigure('Visible', 'off');
            app.ControlPanelUIFigure.Name = 'Control Panel';
            app.ControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ControlPanelUIFigureCloseRequest, true);
            app.ViewOptionButtonGroup = uibuttongroup(app.ControlPanelUIFigure);
            app.ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewOptionButtonGroupSelectionChanged, true);
            app.ViewOptionButtonGroup.Title = 'View Option';
            app.XButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.XButton.Text = 'X';
            app.YButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.ZButton.Text = 'Z';
            app.ALLButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.ALLButton.Text = 'ALL';
            app.ALLButton.Value = true;
            app.CrosshairCheckBox = uicheckbox(app.ViewOptionButtonGroup);
            app.CrosshairCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrosshairCheckBoxValueChanged, true);
            app.CrosshairCheckBox.Text = 'Crosshair';
            app.CrosshairCheckBox.Value = true;
            app.XSliderLabel = uilabel(app.ControlPanelUIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.ControlPanelUIFigure);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.ControlPanelUIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.ControlPanelUIFigure);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.ControlPanelUIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.ControlPanelUIFigure);
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.XEditField = uieditfield(app.ControlPanelUIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditField = uieditfield(app.ControlPanelUIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditField = uieditfield(app.ControlPanelUIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.ControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = map_viewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlPanelUIFigure)
        end
    end
end